classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PressureLevel                 matlab.ui.control.UIAxes
        TabGroup                      matlab.ui.container.TabGroup
        ManualTab                     matlab.ui.container.Tab
        ManualPressureControlPanel    matlab.ui.container.Panel
        Ch1mbarLabel                  matlab.ui.control.Label
        Pres1Slider                   matlab.ui.control.Slider
        Ch2mbarLabel                  matlab.ui.control.Label
        Pres2Slider                   matlab.ui.control.Slider
        Ch3mbarLabel                  matlab.ui.control.Label
        Pres3Slider                   matlab.ui.control.Slider
        Ch4mbarLabel                  matlab.ui.control.Label
        Pres4Slider                   matlab.ui.control.Slider
        Pres1Open                     matlab.ui.control.StateButton
        Pres2Open                     matlab.ui.control.StateButton
        Pres3Open                     matlab.ui.control.StateButton
        Pres4Open                     matlab.ui.control.StateButton
        Pres1Spinner                  matlab.ui.control.Spinner
        Pres2Spinner                  matlab.ui.control.Spinner
        Pres3Spinner                  matlab.ui.control.Spinner
        Pres4Spinner                  matlab.ui.control.Spinner
        RampTab                       matlab.ui.container.Tab
        P_startmbarLabel              matlab.ui.control.Label
        RampStart                     matlab.ui.control.Spinner
        P_endmbarLabel                matlab.ui.control.Label
        RampEnd                       matlab.ui.control.Spinner
        RampTabs                      matlab.ui.container.TabGroup
        RampTimeTab                   matlab.ui.container.Tab
        RampTimeSpinnerLabel          matlab.ui.control.Label
        RampTimeSpinner               matlab.ui.control.Spinner
        SteepnessTab                  matlab.ui.container.Tab
        SteepnessmbarsSpinnerLabel    matlab.ui.control.Label
        SteepnessmbarsSpinner         matlab.ui.control.Spinner
        ElveflowMATLABInterfaceLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        myTimer;
        
        err = 0 % Error Variable
        Instrument_Name; %01C9D9C3 is the name of my instrument
        Inst_ID; %pointer to store the instrument ID (no array)
        
        CalibSize = 1000;
        Calibration; %create a pointer for calibration
        
        Press_Array; %pointer to store the 4 pressure channels
        Press_Value; %pointer to store the pressure of 1 channel
    end
    
    methods (Access = private)
        
        function setPressure(app,p,chan)
            %TODO Add array support
            app.err = OB1_Set_Press(app.Inst_ID.Value, chan, p, app.Calibration, app.CalibSize);
            CheckError(app.err);   
        end
        
        function result = getPressure(app,chan)
            %TODO Add array support
            app.err = OB1_Get_Press(app.Inst_ID.Value ,chan, 1, app.Calibration, app.Press_Value, app.CalibSize);
            CheckError(app.err);
            result = app.Press_Value.Value;
        end
        
        function results = updatePressure(app)
            for chan = 0:3
                pres = getPressure(app,chan);
                disp(pres);
                switch chan + 1
                    case 1
                        app.Pres1Slider.Value = pres;
                        app.Pres1Spinner.Value = pres;
                    case 2
                        app.Pres2Slider.Value = pres;
                        app.Pres2Spinner.Value = pres;
                    case 3
                        app.Pres3Slider.Value = pres;
                        app.Pres3Spinner.Value = pres;
                    case 4
                        app.Pres4Slider.Value = pres;
                        app.Pres4Spinner.Value = pres;
                end
            end
            results = true;
        end
        
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Device Initialization
            
            %move to path of the GUI
            actualPath = which(mfilename);
            if ~strcmp(actualPath,pwd)
                idcs=strfind(actualPath,'\')
                newdir = actualPath(1:idcs(end)-1)
                cd(newdir);
            end
            %define here the directory where .m, Dll and this script are
            if contains(computer,'64')
                addpath('./MATLAB_64/Example');% path for Mathlab"***.m" file
                addpath('./MATLAB_64/MATLAB_64/DLL64');% path for DLL library
                addpath('./MATLAB_64/MATLAB_64');% path for DLL library
            else
                addpath('./MATLAB_32/Example');% path for Mathlab"***.m" file
                addpath('./MATLAB_32/MATLAB_32/DLL32');% path for DLL library
                addpath('./MATLAB_32/MATLAB_32');% path for DLL library
            end
            addpath('./')% path for your script
            
            %check the toolchain setup
            try
                mex -setup C++
            catch
                error('Compiler does not work properly!');
            end
            
            %%Always use Elveflow_Load at the begining, it load the DLL
            Elveflow_Load;
            
            app.Instrument_Name = libpointer('cstring','01E5A2C4'); %01C9D9C3 is the name of my instrument
            app.Inst_ID=libpointer('int32Ptr',zeros(1,1)); %pointer to store the instrument ID (no array)
            
            app.Calibration = libpointer('doublePtr',zeros( app.CalibSize,1)); %create a pointer for calibration
            
            app.Press_Array = libpointer('doublePtr',zeros(4,1)); %pointer to store the 4 pressure channels
            app.Press_Value= libpointer('doublePtr',zeros(1,1));%pointer to store the pressure of 1 channel
            
            %Initiate the device and all regulators and sensors types (see user
            %guide for help
            % Z_regulator_type_none 0
            % Z_regulator _type__0_200_mbar 1
            % Z_regulator _type__0_2000_mbar 2
            % Z_regulator _type__0_8000_mbar 3
            % Z_regulator_type_m1000_1000_mbar 4
            % Z_regulator_type_m1000_6000_mbar 5
            % Z_sensor_type_none 0
            % Z_sensor _type_Flow_1_5_muL_min 1
            % Z_sensor _type_Flow_7_muL_min 2
            % Z_sensor _type_Flow_50_muL_min 3
            % Z_sensor _type_Flow_80_muL_min 4
            app.err=OB1_Initialization(app.Instrument_Name,1,2,4,3,app.Inst_ID);
            CheckError(app.err);
            
            %add digital flow sensor. Valid for OB1 MK3+ only, if sensor not detected it will throw an error ;
            %err=OB1_Add_Sens(Inst_ID.Value,1,5,1,0,7); %add digital flow sensor. Valid for OB1 MK3+ only, if sensor not detected it will throw an error ;
            %CheckError(err);
            
            disp(strcat('Instrument ID = ', num2str(app.Inst_ID.Value)));%show the instrument number
            
            % use default calibration
            app.err = Elveflow_Calibration_Default(app.Calibration, app.CalibSize);
            CheckError(app.err);
            
            %% Timer prototype for pressure
            app.myTimer = timer('Period',.1,'ExecutionMode', 'fixedSpacing', 'BusyMode', 'drop', 'TasksToExecute', Inf);
            app.myTimer.TimerFcn = @(~, ~) updatePressure(app);
            start(app.myTimer);
            
        end

        % Value changed function: Pres1Slider
        function Pres1SliderValueChanged(app, event)
            value = app.Pres1Slider.Value;
            app.Pres1Spinner.Value = value;
            setPressure(app,value,1);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if exist("app.Inst_ID.Value","var") == 1
            app.err=OB1_Destructor(app.Inst_ID.Value);%close communication with the instrument
            CheckError(app.err);
            end
            
            Elveflow_Unload;% Unload DLL
            
            stop(app.myTimer);
            
            % Terminate program
            delete(app)
            
        end

        % Value changed function: Pres2Slider
        function Pres2SliderValueChanged(app, event)
            value = app.Pres2Slider.Value;
            app.Pres2Spinner.Value = value;
            setPressure(app,value,2);
            
        end

        % Value changed function: Pres3Spinner
        function Pres3SpinnerValueChanged(app, event)
            value = app.Pres3Spinner.Value;
              app.Pres3Spinner.Value = value;
            setPressure(app,value,3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PressureLevel = uiaxes(app.UIFigure);
            title(app.PressureLevel, 'Pressure Level')
            xlabel(app.PressureLevel, 't / s')
            ylabel(app.PressureLevel, 'P / mbar')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ManualTab = uitab(app.TabGroup);
            app.ManualTab.Title = 'Manual';
            app.ManualPressureControlPanel = uipanel(app.ManualTab);
            app.ManualPressureControlPanel.Title = 'Manual Pressure Control';
            app.Ch1mbarLabel = uilabel(app.ManualPressureControlPanel);
            app.Ch1mbarLabel.Text = 'Ch1  [mbar]';
            app.Pres1Slider = uislider(app.ManualPressureControlPanel);
            app.Pres1Slider.Limits = [0 200];
            app.Pres1Slider.ValueChangedFcn = createCallbackFcn(app, @Pres1SliderValueChanged, true);
            app.Ch2mbarLabel = uilabel(app.ManualPressureControlPanel);
            app.Ch2mbarLabel.Text = 'Ch2 [mbar]';
            app.Pres2Slider = uislider(app.ManualPressureControlPanel);
            app.Pres2Slider.Limits = [0 2000];
            app.Pres2Slider.ValueChangedFcn = createCallbackFcn(app, @Pres2SliderValueChanged, true);
            app.Ch3mbarLabel = uilabel(app.ManualPressureControlPanel);
            app.Ch3mbarLabel.Text = 'Ch3 [mbar]';
            app.Pres3Slider = uislider(app.ManualPressureControlPanel);
            app.Pres3Slider.Limits = [0 8000];
            app.Ch4mbarLabel = uilabel(app.ManualPressureControlPanel);
            app.Ch4mbarLabel.Text = 'Ch4 [mbar]';
            app.Pres4Slider = uislider(app.ManualPressureControlPanel);
            app.Pres4Slider.Limits = [-1000 1000];
            app.Pres1Open = uibutton(app.ManualPressureControlPanel, 'state');
            app.Pres1Open.Text = 'Close';
            app.Pres2Open = uibutton(app.ManualPressureControlPanel, 'state');
            app.Pres2Open.Text = 'Close';
            app.Pres3Open = uibutton(app.ManualPressureControlPanel, 'state');
            app.Pres3Open.Text = 'Close';
            app.Pres4Open = uibutton(app.ManualPressureControlPanel, 'state');
            app.Pres4Open.Text = 'Close';
            app.Pres1Spinner = uispinner(app.ManualPressureControlPanel);
            app.Pres1Spinner.ValueDisplayFormat = '%4.2f';
            app.Pres2Spinner = uispinner(app.ManualPressureControlPanel);
            app.Pres2Spinner.ValueDisplayFormat = '%4.2f';
            app.Pres3Spinner = uispinner(app.ManualPressureControlPanel);
            app.Pres3Spinner.ValueDisplayFormat = '%4.2f';
            app.Pres3Spinner.ValueChangedFcn = createCallbackFcn(app, @Pres3SpinnerValueChanged, true);
            app.Pres4Spinner = uispinner(app.ManualPressureControlPanel);
            app.Pres4Spinner.ValueDisplayFormat = '%4.2f';
            app.RampTab = uitab(app.TabGroup);
            app.RampTab.Title = 'Ramp';
            app.P_startmbarLabel = uilabel(app.RampTab);
            app.P_startmbarLabel.Text = 'P_start [mbar]';
            app.RampStart = uispinner(app.RampTab);
            app.P_endmbarLabel = uilabel(app.RampTab);
            app.P_endmbarLabel.Text = 'P_end [mbar]';
            app.RampEnd = uispinner(app.RampTab);
            app.RampTabs = uitabgroup(app.RampTab);
            app.RampTimeTab = uitab(app.RampTabs);
            app.RampTimeTab.Title = 'Ramp Time';
            app.RampTimeSpinnerLabel = uilabel(app.RampTimeTab);
            app.RampTimeSpinnerLabel.Text = 'Ramp Time';
            app.RampTimeSpinner = uispinner(app.RampTimeTab);
            app.SteepnessTab = uitab(app.RampTabs);
            app.SteepnessTab.Title = 'Steepness';
            app.SteepnessmbarsSpinnerLabel = uilabel(app.SteepnessTab);
            app.SteepnessmbarsSpinnerLabel.Text = 'Steepness [mbar/s]';
            app.SteepnessmbarsSpinner = uispinner(app.SteepnessTab);
            app.ElveflowMATLABInterfaceLabel = uilabel(app.UIFigure);
            app.ElveflowMATLABInterfaceLabel.Text = 'Elveflow MATLAB Interface';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end